classdef annotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnnotatorUIFigure           matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        EnableStateScoringCheckBox  matlab.ui.control.CheckBox
        ampScaleLabel_3             matlab.ui.control.Label
        ampScaleLabel_2             matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        FilterButton                matlab.ui.control.StateButton
        SpectrogramButton           matlab.ui.control.Button
        maxFreqEditField            matlab.ui.control.EditField
        maxFreqLabel                matlab.ui.control.Label
        minFreqEditField            matlab.ui.control.EditField
        minFreqEditFieldLabel       matlab.ui.control.Label
        ch1Button_11                matlab.ui.control.StateButton
        ch10Button                  matlab.ui.control.StateButton
        ch7Button                   matlab.ui.control.StateButton
        ch4Button                   matlab.ui.control.StateButton
        ch2Button                   matlab.ui.control.StateButton
        ch9Button                   matlab.ui.control.StateButton
        ch8Button                   matlab.ui.control.StateButton
        ch6Button                   matlab.ui.control.StateButton
        ch5Button                   matlab.ui.control.StateButton
        ch3Button                   matlab.ui.control.StateButton
        secondLabel                 matlab.ui.control.Label
        Amplitude1xLabel            matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Time_Label                  matlab.ui.control.Label
        ampScaleLabel               matlab.ui.control.Label
        ch10Button_s                matlab.ui.control.StateButton
        ch9Button_s                 matlab.ui.control.StateButton
        ch8Button_s                 matlab.ui.control.StateButton
        ch7Button_s                 matlab.ui.control.StateButton
        ch6Button_s                 matlab.ui.control.StateButton
        ch5Button_s                 matlab.ui.control.StateButton
        ch4Button_s                 matlab.ui.control.StateButton
        ch3Button_s                 matlab.ui.control.StateButton
        ch2Button_s                 matlab.ui.control.StateButton
        ch1Button_s                 matlab.ui.control.StateButton
        Slider                      matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        EventsButtonGroup           matlab.ui.container.ButtonGroup
        OtherButton                 matlab.ui.control.RadioButton
        ArtefactsButton             matlab.ui.control.RadioButton
        SpikesButton                matlab.ui.control.RadioButton
        HFOsButton                  matlab.ui.control.RadioButton
        ButtonGroup                 matlab.ui.container.ButtonGroup
        nonREMButton                matlab.ui.control.RadioButton
        REMButton                   matlab.ui.control.RadioButton
        AwakeButton                 matlab.ui.control.RadioButton
        UndefinedButton             matlab.ui.control.RadioButton
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        HFOsTab                     matlab.ui.container.Tab
        hfo_Table                   matlab.ui.control.Table
        SpikesTab                   matlab.ui.container.Tab
        spikes_Table                matlab.ui.control.Table
        ArtefactsTab                matlab.ui.container.Tab
        art_Table                   matlab.ui.control.Table
        OtherTab                    matlab.ui.container.Tab
        other_Table                 matlab.ui.control.Table
        FileMenu                    matlab.ui.container.Menu
        OpenEDFMenu                 matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        LoadAnnotationMenu          matlab.ui.container.Menu
        Menu                        matlab.ui.container.Menu
        AmplitudeMenu               matlab.ui.container.Menu
        xMenu                       matlab.ui.container.Menu
        xMenu_2                     matlab.ui.container.Menu
        xMenu_3                     matlab.ui.container.Menu
        xMenu_4                     matlab.ui.container.Menu
        xMenu_5                     matlab.ui.container.Menu
        xMenu_12                    matlab.ui.container.Menu
        xMenu_13                    matlab.ui.container.Menu
        xMenu_6                     matlab.ui.container.Menu
        xMenu_7                     matlab.ui.container.Menu
        xMenu_8                     matlab.ui.container.Menu
        xMenu_9                     matlab.ui.container.Menu
        xMenu_10                    matlab.ui.container.Menu
        xMenu_11                    matlab.ui.container.Menu
        TimeScaleMenu               matlab.ui.container.Menu
        secpageMenu                 matlab.ui.container.Menu
        secpageMenu_2               matlab.ui.container.Menu
        secpageMenu_3               matlab.ui.container.Menu
        secpageMenu_4               matlab.ui.container.Menu
        secpageMenu_5               matlab.ui.container.Menu
        secpageMenu_6               matlab.ui.container.Menu
        secpageMenu_7               matlab.ui.container.Menu
        secpageMenu_8               matlab.ui.container.Menu
        secpageMenu_9               matlab.ui.container.Menu
        secpageMenu_10              matlab.ui.container.Menu
        secpageMenu_11              matlab.ui.container.Menu
        minpageMenu                 matlab.ui.container.Menu
        minpageMenu_2               matlab.ui.container.Menu
        minpageMenu_3               matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        file_name % loaded file name
        data % matrix of signals
        header % header of the read file
        Events_array % array containing all events objects
        Saved_Events_array % array containing all saved events objects
        currEvent % ID of current event
        currDispChannels % index of channels being displayed 1 show, 0 hide
        currSelectedChannels % index of selected channels (-1 means none is displayed)
        ampScale % amplitude scaling example 0.5 is half and 2 is double
        timeScale % time displayed per page
        currTime % time of beggining of current page
        eventType % 1 HFO, 2 Spike, 3 Artefacts, 4 Other
        eventColor % 'g' HFO, 'b' Spikes, 'r' Artefacts, 'k' Other
        selected_cell % index of last selected cell in one of the tables
        selected_table % index of last selected table
        vigilance_state % vector equal to data defining state
        Saved_vigilance_state % vector equal to data saved state
        save_file_name % file name to be used to save xls
        save_path_name % file path to be used to save xls
        spect % spectrogram parameters
    end

    methods (Access = private)

        function myUpdateAppLayout(app, ~)

            currentFigureWidth = app.AnnotatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {'1x', currentFigureWidth*0.2};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
                pause(0.2)
                plot_signals(app);
            end
        end
        function [cell2write,cell2writeID] = event_array2cell(app,i)

            cell2write{1,1} = 'name';
            cell2write{1,2} = 'Start Time (s)';
            cell2write{1,3} = 'End Time (s)';
            cell2write{1,4} = 'Channels';
            cell2writeID{1,1} = 'ID';
            for ev = 1:length(app.Events_array{i})
                % check if event exists
                if ~isempty(app.Events_array{i}{ev})
                    % convert saved events array into cell
                    cell2write{ev+1,1} = app.Events_array{i}{ev}.name;
                    cell2write{ev+1,2} = app.Events_array{i}{ev}.start_time;
                    cell2write{ev+1,3} = app.Events_array{i}{ev}.end_time;
                    % get channels labels
                    selected_channels_labels = '';
                    if all(app.Events_array{i}{ev}.channels==0)
                        channels = ones(length(app.Events_array{i}{ev}.channels),1);
                    else
                        channels = app.Events_array{i}{ev}.channels;
                    end
                    if all(channels)
                        selected_channels_labels = 'all';
                    else
                        for ch=1:length(app.header.label)
                            if channels(ch)==1
                                selected_channels_labels = [selected_channels_labels app.header.label{ch} ','];
                            end
                        end
                        selected_channels_labels = selected_channels_labels(1:end-1);
                    end
                    cell2write{ev+1,4} = selected_channels_labels;
                    cell2writeID{ev+1,1} = app.Events_array{i}{ev}.ID;
                end
            end
        end

        function vigCell = vigilance2cell(app)

            states = {'Undefined','Awake','REM','nonREM'};
            vigCell{1,1} = 'start time';
            vigCell{1,2} = 'end time';
            vigCell{1,3} =  'state';
            count = 0;
            for state = 0:3
                A = app.vigilance_state;
                A = A == state;
                out = double(diff([~A(1);A(:)]) == 1);
                v = accumarray(cumsum(out).*A(:)+1,1);
                out(out == 1) = v(2:end);
                idx = find(out~=0);
                for i = 1:length(idx)
                    count = count+1;
                    vigCell{count,1} = (idx(i)-1)/app.header.Fs;
                    vigCell{count,2} = out(idx(i))/app.header.Fs+(idx(i)-1)/app.header.Fs;
                    vigCell{count,3} = states{state+1};
                end
            end
        end

        function save_xls(app)

            filename = app.save_file_name;
            path_name = app.save_path_name;
            sheets = {'HFOs','Spikes','Artefacts','Other','State'};

            % clear all sheets if found
            if exist(fullfile(path_name,filename),'file')
                for SheetNum=1:length(sheets)
                    Raw=readcell(fullfile(path_name,filename),...
                        'sheet',SheetNum);
                    [Raw{:, :}]=deal(NaN);
                    writecell(Raw, fullfile(path_name,filename),...
                        'Sheet',SheetNum,'Range', 'A1');
                end
            end
            % repopulate with new data
            for i =1:length(app.Events_array)
                [cell2write,cell2writeID] =  event_array2cell(app,i);
                writecell(cell2write,fullfile(path_name,filename),...
                    'Sheet',sheets{i},'Range', 'A1');
                writecell(cell2writeID,fullfile(path_name,filename),...
                    'Sheet',sheets{i},'Range', 'G1');
            end
            % save vigilance state
            disp('saving... please wait');
            vigCell = vigilance2cell(app);
            writecell(vigCell,fullfile(path_name,filename),...
                'Sheet','State','Range', 'A1');
            disp('file saved succesfully');

        end

        function Load_rhfe(app,file_name,file_path)

            event_type = {'HFO', 'Spike', 'Artifact', 'Other'};
            event_name = {'HFO','SPK','ART','OTH'};
            tables_array = {app.hfo_Table,app.spikes_Table,app.art_Table,app.other_Table};
            events_Color = {'g','b','r','k'};
            % rectangle paramters
            offset_rect = 0.01;
            start_pos = app.UIAxes.YLim(2)*offset_rect;
            end_pos = app.UIAxes.YLim(2)*(1-2*offset_rect);
            if ~isempty(file_name)
                rhfe = load(fullfile(file_path,file_name),'-mat');
                rhfe = rmfield(rhfe,{'st_FileData'});
                electrodes_names = fieldnames(rhfe);
                ev_count = 1;
                all_events = {{},{},{},{}};
                ev_type_idx = [];
                delete(findall(app.UIAxes,'Type','rectangle'));% delete all existing events
                app.currEvent = [];
                compt_ev = 0;
                for i =1:length(electrodes_names)
                    s_data =   rhfe.(electrodes_names{i});
                    for j =1:size(s_data.v_Intervals,1)
                        switch  rhfe.(electrodes_names{i}).st_HFOInfo.v_EvType(j)
                            case 3
                                ev_type_idx=4;% ripple = other
                                compt_ev = 1;
                            case 1 % it was 4
                                ev_type_idx=1;% fripple = hfo
                                compt_ev = 1;
                            case 5
                                ev_type_idx=2;% spikes
                                compt_ev = 1;
                            case 6
                                ev_type_idx=3;% artefacts
                                compt_ev = 1;
                            otherwise
                                ev_type_idx = [];% no compatible events were detected
                        end
                        if ~isempty(ev_type_idx)
                            all_events{ev_type_idx}{ev_count,1} = event_type{ev_type_idx};
                            all_events{ev_type_idx}{ev_count,2} = s_data.st_HFOInfo.m_EvtLims(j,1)/s_data.st_HFOInfo.s_Sampling;
                            all_events{ev_type_idx}{ev_count,3} = s_data.st_HFOInfo.m_EvtLims(j,2)/s_data.st_HFOInfo.s_Sampling;
                            all_events{ev_type_idx}{ev_count,4} = electrodes_names{i};
                            all_events{ev_type_idx}{ev_count,5} = java.util.UUID.randomUUID;
                            all_events{ev_type_idx}{ev_count,6} = 1; % unique so far
                            ev_count = ev_count +1;
                        end
                    end
                end
                if compt_ev == 1
                    % concatenate events of same type happening at the same time
                    jitter = 0.2;
                    app.Events_array = {{},{},{},{}};
                    for typeEv = 1:length(event_type)
                        tabes_events ={};
                        unique_ev = 0;
                        for ev = 1:size(all_events{typeEv})
                            if ~isempty(all_events{typeEv})
                                if all_events{typeEv}{ev,6} == 1 % check if currently unique
                                    unique_ev = unique_ev +1;
                                    selected_ch = all_events{typeEv}{ev,4};
                                    for other_ev = ev+1:size(all_events{typeEv},1)
                                        if ~strcmp(all_events{typeEv}{ev,4},all_events{typeEv}{other_ev,4})% check if not same channel
                                            % check if they appear at the same time
                                            if abs(all_events{typeEv}{ev,2}-all_events{typeEv}{other_ev,2})<=jitter
                                                selected_ch = [selected_ch ',' all_events{typeEv}{other_ev,4}];
                                                all_events{typeEv}{other_ev,6} = 0; % not unique anymore
                                            end
                                        end
                                    end
                                    % create rectangle
                                    rect = rectangle(app.UIAxes,'Position',[all_events{typeEv}{ev,2}*app.header.Fs ...
                                        start_pos (all_events{typeEv}{ev,3}-all_events{typeEv}{ev,2})*app.header.Fs end_pos],...
                                        'Curvature',0.2,'EdgeColor',events_Color{typeEv},'LineWidth',2);
                                    app.currEvent = my_event(all_events{typeEv}{ev,5},all_events{typeEv}{ev,2},all_events{typeEv}{ev,3},...
                                        selected_ch,[event_name{typeEv} '_' num2str(unique_ev)],typeEv,events_Color{typeEv},rect,1);
                                    app.Events_array{typeEv}{unique_ev} = app.currEvent;
                                    tabes_events{unique_ev,1} = [event_name{typeEv} '_' num2str(unique_ev)];
                                    tabes_events{unique_ev,2} = all_events{typeEv}{ev,2};
                                    tabes_events{unique_ev,3} = all_events{typeEv}{ev,3};
                                    tabes_events{unique_ev,4} = selected_ch;
                                    tabes_events{unique_ev,5} = char(all_events{typeEv}{ev,5}.toString);
                                end
                            end
                        end
                        % populate tables
                        set(tables_array{typeEv}, 'data', tabes_events);
                    end
                else
                    disp('No compatible events were detected, Loading failed...')
                    return
                end
            end
        end

        function Load_xls(app,file_name,file_path)

            if ~isempty(app.data)
                sheets = {'HFOs','Spikes','Artefacts','Other'};
                tables_array = {app.hfo_Table,app.spikes_Table,app.art_Table,app.other_Table};
                events_Color = {'g','b','r','k'};
                % rectangle paramters
                offset_rect = 0.01;
                start_pos = app.UIAxes.YLim(2)*offset_rect;
                end_pos = app.UIAxes.YLim(2)*(1-2*offset_rect);
                % reset variables
                app.Events_array = {{},{},{},{}};
                delete(findall(app.UIAxes,'Type','rectangle'));% delete all existing events
                app.currEvent = [];
                app.vigilance_state=zeros(size(app.data,2),1);
                % populate Events
                for sheet = 1:length(sheets) % read only events now
                    T = readtable(fullfile(file_path,file_name),'Sheet',sheets{sheet});
                    C = table2cell(T);
                    C(:,[5,6]) = [];% remove nans
                    % populate Events_array and plot
                    ev2beDel = [];
                    for ev = 1:size(C,1)
                        try
                            rect = rectangle(app.UIAxes,'Position',[C{ev,2}*app.header.Fs start_pos (C{ev,3}-C{ev,2})*app.header.Fs end_pos],'Curvature',0.2,'EdgeColor',events_Color{sheet},'LineWidth',2);
                            % create event
                            app.currEvent = my_event(C{ev,5},C{ev,2},C{ev,3},C{ev,4},C{ev,1},sheet,events_Color{sheet},rect,1);
                            app.Events_array{sheet}{ev} = app.currEvent;
                        catch exception
                            disp(['event number ' num2str(ev) ' was not loaded '  exception.message]);
                            ev2beDel = [ev2beDel ev];
                        end
                    end
                    % populate tables
                    C(ev2beDel,:) = [];%remove unloaded event from table
                    app.Events_array{sheet}(ev2beDel) = []; % remove unloaded event from save array
                    set(tables_array{sheet}, 'data', C);
                end
                % populate states
                delete(findall(app.UIAxes,'Type','patch')); % delete all existing scores
                app.vigilance_state = zeros(size(app.data,2),1); % reset vigilance state to undefined
                C = table2cell(readtable(fullfile(file_path,file_name),'Sheet','State')); % read state sheet
                app.AwakeButton.Value = 1;
                for st = 1:size(C,1)
                    switch C{st,3}
                        case 'Awake'
                            app.vigilance_state(C{st,1}*app.header.Fs:C{st,2}*app.header.Fs) = 1;
                            patch(app.UIAxes,'vertices',[C{st,1}*app.header.Fs app.UIAxes.YLim(1); C{st,2}*app.header.Fs app.UIAxes.YLim(1);...
                                C{st,1}*app.header.Fs app.UIAxes.YLim(2); C{st,2}*app.header.Fs app.UIAxes.YLim(2)],'faces',[1 2 4 3],...
                                'EdgeColor','none','FaceColor','c','FaceAlpha',0.05);
                        case 'REM'
                            app.vigilance_state(C{st,1}*app.header.Fs:C{st,2}*app.header.Fs) = 2;
                            patch(app.UIAxes,'vertices',[C{st,1}*app.header.Fs app.UIAxes.YLim(1); C{st,2}*app.header.Fs app.UIAxes.YLim(1);...
                                C{st,1}*app.header.Fs app.UIAxes.YLim(2); C{st,2}*app.header.Fs app.UIAxes.YLim(2)],'faces',[1 2 4 3],...
                                'EdgeColor','none','FaceColor','g','FaceAlpha',0.05);
                        case 'nonREM'
                            app.vigilance_state(C{st,1}*app.header.Fs:C{st,2}*app.header.Fs) = 3;
                            patch(app.UIAxes,'vertices',[C{st,1}*app.header.Fs app.UIAxes.YLim(1); C{st,2}*app.header.Fs app.UIAxes.YLim(1);...
                                C{st,1}*app.header.Fs app.UIAxes.YLim(2); C{st,2}*app.header.Fs app.UIAxes.YLim(2)],'faces',[1 2 4 3],...
                                'EdgeColor','none','FaceColor','y','FaceAlpha',0.05);
                    end
                end
            end
        end

        function plot_vigilance(app)

            color = 'w';
            if ((~isempty(app.data)) && (app.EnableStateScoringCheckBox.Value == 1))
                switch app.ButtonGroup.SelectedObject
                    case app.AwakeButton
                        color = 'c';
                        alpha = 0.05;
                        app.vigilance_state(app.UIAxes.XLim(1):app.UIAxes.XLim(2))=1;
                    case app.REMButton
                        color = 'g';
                        alpha = 0.05;
                        app.vigilance_state(app.UIAxes.XLim(1):app.UIAxes.XLim(2))=2;
                    case app.nonREMButton
                        color = 'y';
                        alpha = 0.05;
                        app.vigilance_state(app.UIAxes.XLim(1):app.UIAxes.XLim(2))=3;
                    case app.UndefinedButton
                        color = 'w';
                        alpha = 0;
                        app.vigilance_state(app.UIAxes.XLim(1):app.UIAxes.XLim(2))=0;
                end
                % get patches in current xlim
                curr_patches = findall(app.UIAxes,'type','patch');
                found = 0;
                if ~isempty(curr_patches)
                    for i = 1:length(curr_patches)
                        x1 = curr_patches(i).Vertices(1,1);
                        x2 = curr_patches(i).Vertices(2,1);
                        if ((x1>=app.UIAxes.XLim(1) && x1 <=app.UIAxes.XLim(2)) ||...
                                (x2>=app.UIAxes.XLim(1) && x2 <=app.UIAxes.XLim(2)))
                            curr_patches(i).FaceColor = color;
                            curr_patches(i).FaceAlpha = alpha;
                            found = 1;
                        end
                    end
                end
                if (found == 0 && ~strcmp(color,'w'))
                    patch(app.UIAxes,'vertices',[app.UIAxes.XLim(1) app.UIAxes.YLim(1); app.UIAxes.XLim(2) app.UIAxes.YLim(1);...
                        app.UIAxes.XLim(1) app.UIAxes.YLim(2); app.UIAxes.XLim(2) app.UIAxes.YLim(2)],'faces',[1 2 4 3],...
                        'EdgeColor','none','FaceColor',color,'FaceAlpha',alpha);
                end
            end
        end
        function ev = search_event(app)

            tables_array = {app.hfo_Table, app.spikes_Table, app.art_Table, app.other_Table};
            events_2_search = app.Events_array{app.selected_table};
            % get ID of selected row (5 is ID)
            table_data=get(tables_array{app.selected_table}, 'data');
            selected_ID = table_data{app.selected_cell(1),5};
            ev = [];
            for ev_idx = 1:length(events_2_search)
                if strcmp(events_2_search{ev_idx}.ID,selected_ID)
                    ev = ev_idx;
                    break;
                end
            end

        end

        function ev_found = check_if_saved(app)
            % check if current event is saved in the Events Array
            events_2_search = app.Events_array{app.selected_table};
            current_ID = app.currEvent.ID;
            ev_found = 0;
            for ev_idx = 1:length(events_2_search)
                if strcmp(events_2_search{ev_idx}.ID,current_ID)
                    ev_found = 1;
                    break;
                end
            end
        end

        function update_thickness(app,ch,value)

            % get all visible line objects
            line_objs = findobj(app.UIAxes,'tag',app.header.label{ch});
            if value == 0
                line_objs.LineWidth = 0.4;
            else
                line_objs.LineWidth = 1;
            end
        end

        function update_limits(app)

            if (~isempty(app.data) && any(app.currDispChannels))
                end_idx = min((app.currTime+app.timeScale)*app.header.Fs,size(app.data,2)+app.timeScale*app.header.Fs);
                app.UIAxes.XLim = [floor((app.currTime)*app.header.Fs+1) end_idx];
                app.UIAxes.XTick = floor((app.currTime)*app.header.Fs+1):1*app.header.Fs:end_idx;
                drawnow;
            end

        end

        function plot_signals(app)
            % set renderer
            set(app.AnnotatorUIFigure,'renderer','opengl');
            % clear line objects from axes
            line_objs = findall(app.UIAxes,'Type','Line');
            delete(line_objs);
            % delete current event of not saved
            if  ~isempty(app.currEvent)
                if (app.currEvent.saved == 0 && isgraphics(app.currEvent.rect))
                    delete(app.currEvent.rect)
                end
            end


            if (~isempty(app.data) && any(app.currDispChannels))
                % scale dispayed signals
                norm_Sig = gather(app.data(app.currDispChannels==1,:));
                % check if signal needs to be filtered
                value = app.FilterButton.Value;
                if value == 1
                    norm_Sig = bandpass(norm_Sig,[app.spect.minF app.spect.maxF],app.header.Fs);
                end
                line_width = ones(1,size(app.data,1))*0.4;
                line_width(app.currSelectedChannels==1) = 1;
                % estimate limit of axes
                max_lim = gather(max(max(app.data,[],2) - min(app.data,[],2)));
                offset = max_lim/sum(app.currDispChannels);
                app.UIAxes.YLim = [0 max_lim];
                hold(app.UIAxes, 'on' )
                idx_show = find(app.currDispChannels==1);
                idx_hide = find(app.currDispChannels==0);
                end_idx = min((app.currTime+app.timeScale)*app.header.Fs,size(app.data,2)+app.timeScale*app.header.Fs);
                strt_idx = floor((app.currTime)*app.header.Fs+1);
                for i = 1:sum(app.currDispChannels)
                    line(app.UIAxes,1:length(norm_Sig(i,:)),norm_Sig(i,:).*app.ampScale+offset*(sum(app.currDispChannels)-i+0.5),...
                        'color',[0,0,0,1],'LineWidth',line_width(idx_show(i)),'tag',app.header.label{idx_show(i)});
                end
                app.UIAxes.XLim = [floor((app.currTime)*app.header.Fs+1) end_idx];
                app.UIAxes.XTick = 1:1*app.header.Fs:app.timeScale*app.header.Fs;
                % modify ampScale Labnel to show exactly 100 uV
                app.ampScaleLabel.Position(4) = (100*app.UIAxes.Position(4)/max_lim)*app.ampScale;
                app.ampScaleLabel.Position(2) = app.UIAxes.Position(2)*1.5;
                app.ampScaleLabel.Position(1) = (app.UIAxes.Position(1)+app.UIAxes.Position(3));
                % modify selected channels button position
                selected_button_cell = {app.ch1Button_s,app.ch2Button_s,app.ch3Button_s,app.ch4Button_s,...
                    app.ch5Button_s,app.ch6Button_s,app.ch7Button_s,app.ch8Button_s,app.ch9Button_s,app.ch10Button_s};
                offset_selected = (app.UIAxes.Position(4)-app.ch1Button_s.Position(4))./length(idx_show);
                for ch = 1:length(idx_show)
                    selected_button_cell{idx_show(ch)}.Visible = 'on';
                    selected_button_cell{idx_show(ch)}.Position(2) = ...
                        app.UIAxes.Position(2)+offset_selected*(sum(app.currDispChannels)-ch+0.5)...
                        +app.ch1Button_s.Position(4)/2;
                end
                for ch = 1:length(idx_hide)
                    selected_button_cell{idx_hide(ch)}.Visible = 'off';
                    selected_button_cell{idx_hide(ch)}.Value = 0;
                    app.currSelectedChannels(idx_hide(ch))=0;
                end

                % update positions of rectangles of saved events
                rect_objs = findall(app.UIAxes,'Type','rectangle');
                offset_rect = 0.01;
                start_pos = app.UIAxes.YLim(2)*offset_rect;
                end_pos = app.UIAxes.YLim(2)*(1-2*offset_rect);
                if ~isempty(rect_objs)
                    for idx_rect = 1:size(rect_objs,1)
                        rect_objs(idx_rect).Position(2) = start_pos;
                        rect_objs(idx_rect).Position(4) = end_pos;
                    end
                end
            end
            clear norm_Sig;
        end

        function keypressed = ismousedpressed(~)

            if ~libisloaded('user32')
                try
                    loadlibrary('C:\WINDOWS\system32\user32.dll', 'user32.h');
                catch
                    % user.h is removed on certain recent version of Windows
                    % We replace with a simple customized header file
                    here = fileparts(mfilename('fullpath'));
                    loadlibrary('C:\WINDOWS\system32\user32.dll', [here '\class\WinMouse.h']);
                end
            end
            left    = calllib('user32','GetAsyncKeyState',int32(1)) ~= 0;
            right   = calllib('user32','GetAsyncKeyState',int32(2)) ~= 0;
            scroll  = calllib('user32','GetAsyncKeyState',int32(4)) ~= 0;
            keypressed = max([1 2 3].*double([left right scroll]));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.Box = 'on';
            warning('off','all');
            app.TabGroup.SelectedTab = app.ArtefactsTab;
            app.AnnotatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            % add library
            addpath('./rippleLab_fnc/');

        end

        % Menu selected function: OpenEDFMenu
        function Open_EDF(app, event)

            [filename,file_path] = uigetfile({'*.edf','EDF files (*.edf*)';...
                '*.edf','EDF files (*.EDF)'},'Select a File', 'MultiSelect', 'off');
            if ~isequal(filename,0)
                clear app.data;
                disp(['User selected ', fullfile(file_path,filename)]);
                disp('Loading File...Please wait...');
                app.header = ft_read_header(fullfile(file_path,filename));
                app.data = downsample((ft_read_data(fullfile(file_path,filename),'header', app.header))',2)';
                app.header.Fs = app.header.Fs/2;
                app.file_name = filename;
                % notch filter
                wo = 60/(app.header.Fs/2);
                bw = wo/35;
                [b,a] = iirnotch(wo,bw);
                app.data = filtfilt(b,a,app.data')';
                app.currDispChannels = ones(1,size(app.data,1));
                app.currSelectedChannels = zeros(1,size(app.data,1));
                app.Events_array={{};{};{};{}};
                app.Saved_Events_array = app.Events_array;
                app.currEvent = [];
                app.ampScale = 1;
                app.timeScale = 5;
                app.currTime = 0;
                app.eventType = 3;
                app.eventColor = 'r';
                plot_signals(app);
                disp('File successfully Loaded')
                app.vigilance_state=zeros(size(app.data,2),1);
                app.Saved_vigilance_state = app.vigilance_state;
                app.SaveMenu.Enable = 0;
                app.SaveAsMenu.Enable = 1;
                app.save_file_name = '';
                app.save_path_name = '';
                app.LoadAnnotationMenu.Enable = 1;
                app.spect.maxF = 500;
                app.spect.minF = 30;
                % modify Slider limits
                app.Slider.Limits = [0 size(app.data,2)/app.header.Fs/60];
                app.Slider.MajorTicks = 0:5:app.Slider.Limits(2);
                app.UIAxes.XLabel.String = 'Time (mm:ss)';
                button_cell = {app.ch1Button_11,app.ch2Button,app.ch3Button,app.ch4Button,app.ch5Button...
                    app.ch6Button,app.ch7Button,app.ch8Button,app.ch9Button,app.ch10Button};
                selected_channel_cell = {app.ch1Button_s,app.ch2Button_s,app.ch3Button_s,app.ch4Button_s,...
                    app.ch5Button_s,app.ch6Button_s,app.ch7Button_s,app.ch8Button_s,app.ch9Button_s,app.ch10Button_s};
                % reset tables
                tables_array = {app.hfo_Table,app.spikes_Table,app.art_Table,app.other_Table};
                for  tab = 1:length(tables_array)
                    set(tables_array{tab}, 'Data', [] );
                end
                % enable state buttons for signals
                for ch = 1:size(app.data,1)
                    button_cell{ch}.Visible='on';
                    button_cell{ch}.Value=0;
                    button_cell{ch}.Text = app.header.label{ch};
                    selected_channel_cell{ch}.Visible = 'on';
                    selected_channel_cell{ch}.Value = 0;
                    selected_channel_cell{ch}.Text = app.header.label{ch};

                end
                % disable state buttons for non existing signals
                for ch = size(app.data,1)+1:10
                    button_cell{ch}.Visible='off';
                    selected_channel_cell{ch}.Visible = 'off';
                end
                app.EnableStateScoringCheckBox.Value = 0;
                app.ButtonGroup.Enable = 'off';
            else
                return % user chose cancel
            end

        end

        % Value changed function: Slider
        function slider_moved(app, event)

            value = app.Slider.Value;
            app.currTime = value*60;
            app.Time_Label.Text = [num2str(floor(app.currTime/60)) ':'  num2str(mod(app.currTime,60),'%2.0f') ];
            update_limits(app);

        end

        % Menu selected function: secpageMenu
        function time_scale_100m(app, event)

            app.timeScale = 0.1;
            update_limits(app);
            app.secondLabel.Text = '100 milliseconds';

        end

        % Menu selected function: secpageMenu_2
        function time_scale_200m(app, event)

            app.timeScale = 0.2;
            update_limits(app);
            app.secondLabel.Text = '200 milliseconds';

        end

        % Menu selected function: secpageMenu_5
        function time_scale_2s(app, event)

            app.timeScale = 2;
            update_limits(app);
            app.secondLabel.Text = '2 seconds';

        end

        % Menu selected function: secpageMenu_6
        function time_scale_3s(app, event)

            app.timeScale = 3;
            update_limits(app);
            app.secondLabel.Text = '3 seconds';

        end

        % Menu selected function: secpageMenu_4
        function time_scale_1s(app, event)

            app.timeScale = 1;
            update_limits(app);
            app.secondLabel.Text = '1 second';

        end

        % Menu selected function: secpageMenu_8
        function time_scale_10s(app, event)

            app.timeScale = 10;
            update_limits(app);
            app.secondLabel.Text = '10 seconds';

        end

        % Menu selected function: secpageMenu_9
        function time_scale_15s(app, event)

            app.timeScale = 15;
            update_limits(app);
            app.secondLabel.Text = '15 seconds';

        end

        % Menu selected function: secpageMenu_10
        function time_scale_20s(app, event)

            app.timeScale = 20;
            update_limits(app);
            app.secondLabel.Text = '20 seconds';

        end

        % Menu selected function: secpageMenu_11
        function time_scale_30s(app, event)

            app.timeScale = 30;
            update_limits(app);
            app.secondLabel.Text = '30 seconds';

        end

        % Menu selected function: minpageMenu
        function time_scale_60s(app, event)

            app.timeScale = 60;
            update_limits(app);
            app.secondLabel.Text = '1 minute';

        end

        % Menu selected function: minpageMenu_2
        function time_scale_120s(app, event)

            app.timeScale = 120;
            update_limits(app);
            app.secondLabel.Text = '2 minutes';

        end

        % Menu selected function: secpageMenu_3
        function time_scale_500m(app, event)

            app.timeScale = 0.5;
            update_limits(app);
            app.secondLabel.Text = '0.5 seconds';

        end

        % Menu selected function: secpageMenu_7
        function time_scale_5s(app, event)

            app.timeScale = 5;
            update_limits(app);
            app.secondLabel.Text = '5 seconds';

        end

        % Menu selected function: minpageMenu_3
        function time_scale_5m(app, event)

            app.timeScale = 300;
            update_limits(app);
            app.secondLabel.Text = '5 minutes';

        end

        % Menu selected function: xMenu
        function amp_scale_100(app, event)

            app.ampScale = 0.1;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 0.1x';

        end

        % Menu selected function: xMenu_2
        function amp_scale_200(app, event)

            app.ampScale = 0.2;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 0.2x';

        end

        % Menu selected function: xMenu_3
        function amp_scale_500(app, event)

            app.ampScale = 0.5;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 0.5x';

        end

        % Menu selected function: xMenu_4
        function amp_scale_1(app, event)

            app.ampScale = 1;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 1x';

        end

        % Menu selected function: xMenu_13
        function amp_scale_4(app, event)

            app.ampScale =4;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 4x';

        end

        % Menu selected function: xMenu_7
        function amp_scale_10(app, event)

            app.ampScale = 10;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 10x';

        end

        % Menu selected function: xMenu_8
        function amp_scale_15(app, event)

            app.ampScale = 15;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 15x';

        end

        % Menu selected function: xMenu_9
        function amp_scale_20(app, event)

            app.ampScale = 20;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 20x';

        end

        % Menu selected function: xMenu_10
        function amp_scale_40(app, event)

            app.ampScale = 40;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 40x';

        end

        % Menu selected function: xMenu_11
        function amp_scale_100x(app, event)

            app.ampScale = 100;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 100x';

        end

        % Window scroll wheel function: AnnotatorUIFigure
        function scroll_slider(app, event)

            if ~isempty(app.data)
                cpt = get(app.UIAxes,'CurrentPoint');
                if  ((cpt(1,1)>=app.UIAxes.XLim(1)) && (cpt(1,2)>=app.UIAxes.YLim(1)) && (cpt(1,1) <= app.UIAxes.XLim(2))...
                        && (cpt(1,2)<=app.UIAxes.YLim(2)))
                    verticalScrollCount = event.VerticalScrollCount;
                    new_value = app.currTime+verticalScrollCount*app.timeScale*0.1;
                    if (new_value/60 > app.Slider.Limits(1) && new_value/60 < app.Slider.Limits(2))
                        app.currTime = app.currTime+verticalScrollCount*app.timeScale*0.1;
                        update_limits(app);
                        pause(0.1);
                        if ~isempty(app.data)
                            app.Slider.Value = app.currTime/60;
                        end
                    end
                end
                app.Time_Label.Text = [num2str(floor(app.currTime/60)) ':'  num2str(mod(app.currTime,60),'%2.0f') ];
            end
        end

        % Window key press function: AnnotatorUIFigure
        function keyboard_pressed(app, event)

            key = event.Key;
            switch key
                case 'rightarrow'
                    app.currTime = min(app.currTime + app.timeScale, app.Slider.Limits(2)*60);
                    update_limits(app);
                    app.Time_Label.Text = [num2str(floor(app.currTime/60)) ':'  num2str(mod(app.currTime,60),'%2.0f') ];
                    % update slider
                    if ~isempty(app.data)
                        app.Slider.Value = app.currTime/60;
                    end
                    plot_vigilance(app);

                case 'leftarrow'
                    app.currTime = max(app.currTime - app.timeScale, 0);
                    update_limits(app);
                    app.Time_Label.Text = [num2str(floor(app.currTime/60)) ':'  num2str(mod(app.currTime,60),'%2.0f') ];
                    % update slider
                    if ~isempty(app.data)
                        app.Slider.Value = app.currTime/60;
                    end
                    plot_vigilance(app);
                case 'return'
                    if ~isempty(app.currEvent)
                        if app.currEvent.saved == 0
                            tables_array = {app.hfo_Table, app.spikes_Table, app.art_Table, app.other_Table};
                            table_data=get(tables_array{app.eventType}, 'data');
                            if isempty(table_data)
                                table_data ={};
                            end
                            selected_channels_labels ='';
                            if (all(app.currSelectedChannels==0) || all(app.currSelectedChannels==1))
                                selected_channels_labels ='all';
                            else
                                for i=1:length(app.currSelectedChannels)
                                    if app.currSelectedChannels(i)==1
                                        selected_channels_labels = [selected_channels_labels app.header.label{i} ','];
                                    end
                                end
                            end
                            new_data = {app.currEvent.name,app.currEvent.start_time,app.currEvent.end_time,...
                                selected_channels_labels,app.currEvent.ID};
                            table_data(end+1,:)=new_data;
                            [~,order] = sort([table_data{:,2}],'ascend');
                            set(tables_array{app.eventType}, 'data', table_data(order,:));
                            % check if current event times are same as
                            % reported in table, if no, make them equal
                            if (app.currEvent.start_time ~= new_data{2}) || (app.currEvent.end_time ~= new_data{3})
                                app.currEvent.start_time = new_data{2};
                                app.currEvent.end_time = new_data{3};
                            end
                            app.Events_array{app.eventType}{end+1} = app.currEvent;
                            app.currEvent.saved = 1;
                        end
                    end
                case 'delete'
                    tables_array = {app.hfo_Table, app.spikes_Table, app.art_Table, app.other_Table};
                    if ~isempty(app.selected_cell)
                        if size(app.selected_cell,1)==1
                            ev = search_event(app);
                            table_data=get(tables_array{app.selected_table}, 'data');
                            delete(app.Events_array{app.selected_table}{ev}.rect);
                            app.Events_array{app.selected_table}(ev) = [];
                            % update events table
                            table_data(app.selected_cell(1),:) = [];
                            set(tables_array{app.selected_table}, 'data', table_data);
                            app.selected_cell = [];
                        end
                    end
                case 'space'
                    if ~isempty(app.data)
                        events_ab ={'HFO','SPK','ART','OTH'};
                        strt_time = app.UIAxes.XLim(1)/app.header.Fs;
                        rec_length = app.timeScale;
                        % generate unique ID
                        temp =  java.util.UUID.randomUUID;
                        eventUid = temp.toString;
                        % generate event name
                        event_name = [events_ab{app.eventType} '_' num2str(length(app.Events_array{app.eventType})+1)];
                        % generate rectangle
                        offset_rect = 0.01;
                        start_pos = app.UIAxes.YLim(2)*offset_rect;
                        end_pos = app.UIAxes.YLim(2)*(1-2*offset_rect);
                        rect = rectangle(app.UIAxes,'Position',[strt_time*app.header.Fs start_pos rec_length*app.header.Fs end_pos],'Curvature',0.2,'EdgeColor',app.eventColor,'LineWidth',2);
                        % create event
                        app.currEvent = my_event(eventUid,strt_time,strt_time+rec_length,app.currSelectedChannels,event_name,app.eventType,app.eventColor,rect,0);
                    end
                    if ~isempty(app.currEvent)
                        if app.currEvent.saved == 0
                            tables_array = {app.hfo_Table, app.spikes_Table, app.art_Table, app.other_Table};
                            table_data=get(tables_array{app.eventType}, 'data');
                            if isempty(table_data)
                                table_data ={};
                            end
                            selected_channels_labels ='';
                            if (all(app.currSelectedChannels==0) || all(app.currSelectedChannels==1))
                                selected_channels_labels ='all';
                            else
                                for i=1:length(app.currSelectedChannels)
                                    if app.currSelectedChannels(i)==1
                                        selected_channels_labels = [selected_channels_labels app.header.label{i} ','];
                                    end
                                end
                            end
                            new_data = {app.currEvent.name,app.currEvent.start_time,app.currEvent.end_time,...
                                selected_channels_labels,app.currEvent.ID};
                            table_data(end+1,:)=new_data;
                            [~,order] = sort([table_data{:,2}],'ascend');
                            set(tables_array{app.eventType}, 'data', table_data(order,:));
                            % check if current event times are same as
                            % reported in table, if no, make them equal
                            if (app.currEvent.start_time ~= new_data{2}) || (app.currEvent.end_time ~= new_data{3})
                                app.currEvent.start_time = new_data{2};
                                app.currEvent.end_time = new_data{3};
                            end
                            app.Events_array{app.eventType}{end+1} = app.currEvent;
                            app.currEvent.saved = 1;
                        end
                    end


            end

        end

        % Value changed function: ch1Button_11
        function ch1_stateChanged(app, event)

            value = app.ch1Button_11.Value;
            app.currDispChannels(1) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch2Button
        function ch2_stateChanged(app, event)

            value = app.ch2Button.Value;
            app.currDispChannels(2) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch3Button
        function ch3_stateChanged(app, event)

            value = app.ch3Button.Value;
            app.currDispChannels(3) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch4Button
        function ch4_stateChanged(app, event)

            value = app.ch4Button.Value;
            app.currDispChannels(4) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch5Button
        function ch5_stateChanged(app, event)

            value = app.ch5Button.Value;
            app.currDispChannels(5) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch6Button
        function ch6_stateChanged(app, event)

            value = app.ch6Button.Value;
            app.currDispChannels(6) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch7Button
        function ch7_stateChanged(app, event)

            value = app.ch7Button.Value;
            app.currDispChannels(7) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch8Button
        function ch8_stateChanged(app, event)

            value = app.ch8Button.Value;
            app.currDispChannels(8) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch9Button
        function ch9_stateChanged(app, event)

            value = app.ch9Button.Value;
            app.currDispChannels(9) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch10Button
        function ch10_stateChanged(app, event)

            value = app.ch10Button.Value;
            app.currDispChannels(10) = ~value;
            plot_signals(app);

        end

        % Value changed function: ch9Button_s
        function select_ch9(app, event)

            value = app.ch9Button_s.Value;
            app.currSelectedChannels(9)=value;
            update_thickness(app,9,value);

        end

        % Value changed function: ch2Button_s
        function select_ch2(app, event)

            value = app.ch2Button_s.Value;
            app.currSelectedChannels(2)=value;
            update_thickness(app,2,value);

        end

        % Value changed function: ch3Button_s
        function select_ch3(app, event)

            value = app.ch3Button_s.Value;
            app.currSelectedChannels(3)=value;
            update_thickness(app,3,value);

        end

        % Value changed function: ch4Button_s
        function select_ch4(app, event)

            value = app.ch4Button_s.Value;
            app.currSelectedChannels(4)=value;
            update_thickness(app,4,value);

        end

        % Value changed function: ch5Button_s
        function select_ch5(app, event)

            value = app.ch5Button_s.Value;
            app.currSelectedChannels(5)=value;
            update_thickness(app,5,value);

        end

        % Value changed function: ch6Button_s
        function select_ch6(app, event)

            value = app.ch6Button_s.Value;
            app.currSelectedChannels(6)=value;
            update_thickness(app,6,value);

        end

        % Value changed function: ch7Button_s
        function select_ch7(app, event)

            value = app.ch7Button_s.Value;
            app.currSelectedChannels(7)=value;
            update_thickness(app,7,value);

        end

        % Value changed function: ch8Button_s
        function select_ch8(app, event)

            value = app.ch8Button_s.Value;
            app.currSelectedChannels(8)=value;
            update_thickness(app,8,value);

        end

        % Value changed function: ch1Button_s
        function select_ch1(app, event)

            value = app.ch1Button_s.Value;
            app.currSelectedChannels(1)=value;
            update_thickness(app,1,value);

        end

        % Value changed function: ch10Button_s
        function select_ch10(app, event)

            value = app.ch10Button_s.Value;
            app.currSelectedChannels(10)=value;
            update_thickness(app,10,value);

        end

        % Selection changed function: EventsButtonGroup
        function choose_event_type(app, event)

            selectedButton = app.EventsButtonGroup.SelectedObject;
            switch selectedButton.Text

                case 'HFOs'
                    app.eventType = 1;
                    app.eventColor = [0 1 0 ];
                    app.TabGroup.SelectedTab = app.HFOsTab;
                    app.selected_table = 1;
                case 'Spikes'
                    app.eventType = 2;
                    app.eventColor = [0 0 1 ];
                    app.TabGroup.SelectedTab = app.SpikesTab;
                    app.selected_table = 2;
                case 'Artefacts'
                    app.eventType = 3;
                    app.eventColor = [1 0 0 ];
                    app.TabGroup.SelectedTab = app.ArtefactsTab;
                    app.selected_table = 3;
                case 'Other'
                    app.eventType = 4;
                    app.eventColor = [0 0 0 ];
                    app.TabGroup.SelectedTab = app.OtherTab;
                    app.selected_table = 4;
            end
            if ~isempty(app.currEvent)
                if (app.currEvent.saved == 0 && isgraphics(app.currEvent.rect))
                    delete(app.currEvent.rect);
                    drawnow;
                end
            end
        end

        % Window button down function: AnnotatorUIFigure
        function create_event(app, event)

            events_ab ={'HFO','SPK','ART','OTH'};
            b = get(app.AnnotatorUIFigure,'selectiontype');
            if ~isempty(app.data)
                if strcmp(b,'normal')
                    cpt = get(app.UIAxes,'CurrentPoint');
                    if ((cpt(1,1)>=app.UIAxes.XLim(1)) && (cpt(1,2)>=app.UIAxes.YLim(1)) && (cpt(1,1) <= app.UIAxes.XLim(2))...
                            && (cpt(1,2)<=app.UIAxes.YLim(2)))
                        if ~isempty(app.currEvent)
                            if (app.currEvent.saved == 0 && isgraphics(app.currEvent.rect))
                                delete(app.currEvent.rect);
                            end
                        end
                        strt_time = cpt(1,1)/app.header.Fs;%app.currTime;
                        % generate unique ID
                        temp =  java.util.UUID.randomUUID;
                        eventUid = temp.toString;
                        % generate event name
                        event_name = [events_ab{app.eventType} '_' num2str(length(app.Events_array{app.eventType})+1)];
                        % generate rectangle
                        offset_rect = 0.01;
                        start_pos = app.UIAxes.YLim(2)*offset_rect;
                        end_pos = app.UIAxes.YLim(2)*(1-2*offset_rect);
                        rect = rectangle(app.UIAxes,'Position',[strt_time*app.header.Fs start_pos 0.5 end_pos],'Curvature',0.2,'EdgeColor',app.eventColor,'LineWidth',2);
                        % create event
                        app.currEvent = my_event(eventUid,strt_time,0,app.currSelectedChannels,event_name,app.eventType,app.eventColor,rect,0);
                    end
                elseif strcmp(b,'alt')
                    if ~isempty(app.currEvent)
                        if ((app.currEvent.saved == 0) && (app.currEvent.end_time-app.currEvent.start_time > 0))
                            tables_array = {app.hfo_Table, app.spikes_Table, app.art_Table, app.other_Table};
                            table_data=get(tables_array{app.eventType}, 'data');
                            if isempty(table_data)
                                table_data ={};
                            end
                            selected_channels_labels ='';
                            if (all(app.currSelectedChannels==0) || all(app.currSelectedChannels==1))
                                selected_channels_labels ='all';
                            else
                                for i=1:length(app.currSelectedChannels)
                                    if app.currSelectedChannels(i)==1
                                        selected_channels_labels = [selected_channels_labels app.header.label{i} ','];
                                    end
                                end
                            end
                            new_data = {app.currEvent.name,app.currEvent.start_time,app.currEvent.end_time,...
                                selected_channels_labels,app.currEvent.ID};
                            table_data(end+1,:)=new_data;
                            [~,order] = sort([table_data{:,2}],'ascend');
                            set(tables_array{app.eventType}, 'data', table_data(order,:));
                            % check if current event times are same as
                            % reported in table, if no, make them equal
                            if (app.currEvent.start_time ~= new_data{2}) || (app.currEvent.end_time ~= new_data{3})
                                app.currEvent.start_time = new_data{2};
                                app.currEvent.end_time = new_data{3};
                            end
                            app.Events_array{app.eventType}{end+1} = app.currEvent;
                            app.currEvent.saved = 1;
                        end
                    end
                end
            end
        end

        % Window button up function: AnnotatorUIFigure
        function set_end_time(app, event)
            b = get(app.AnnotatorUIFigure,'selectiontype');
            if strcmp(b,'normal')
                if (~isempty(app.data) && ~isempty(app.currEvent))
                    cpt = get(app.UIAxes,'CurrentPoint');
                    if ((cpt(1,1) >= (app.UIAxes.XLim(1)-0.1*(app.UIAxes.XLim(2)-app.UIAxes.XLim(1)))) ...
                            && (cpt(1,2)>=app.UIAxes.YLim(1)) ...
                            && (cpt(1,1) <= (app.UIAxes.XLim(2)+0.3*(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))))...
                            && (cpt(1,2)<=app.UIAxes.YLim(2)))
                        a = app.currEvent.start_time;
                        app.currEvent.end_time = min(max(a,cpt(1,1)/app.header.Fs),app.UIAxes.XLim(2)/app.header.Fs);
                        app.currEvent.start_time = max(app.UIAxes.XLim(1)/app.header.Fs,min(a,cpt(1,1)/app.header.Fs));
                    end
                end
            end
        end

        % Window button motion function: AnnotatorUIFigure
        function widen_rect(app, event)
            keypressed = app.ismousedpressed();
            if keypressed == 1 %left mouse button pressed
                if (~isempty(app.data) && ~isempty(app.currEvent))
                    if (app.currEvent.saved == 0 && isgraphics(app.currEvent.rect))
                        cpt = get(app.UIAxes,'CurrentPoint');
                        if ((cpt(1,1)>=app.UIAxes.XLim(1)) && (cpt(1,2)>=app.UIAxes.YLim(1)) && (cpt(1,1) <= app.UIAxes.XLim(2))...
                                && (cpt(1,2)<=app.UIAxes.YLim(2)))
                            rect = app.currEvent.rect;
                            offset_rect = 0.01;
                            start_pos = app.UIAxes.YLim(2)*offset_rect;
                            end_pos = app.UIAxes.YLim(2)*(1-2*offset_rect);
                            if cpt(1,1)-app.currEvent.start_time*app.header.Fs>0
                                rect.Position = [ app.currEvent.start_time*app.header.Fs start_pos ...
                                    cpt(1,1)-app.currEvent.start_time*app.header.Fs end_pos];
                            else
                                rect.Position = [ cpt(1,1) start_pos ...
                                    -cpt(1,1)+app.currEvent.start_time*app.header.Fs end_pos];
                            end
                        end
                    end
                end
            end
        end

        % Cell selection callback: hfo_Table
        function HFO_selected_cell(app, event)

            app.selected_cell = event.Indices;
            app.selected_table = 1;
            if size(app.selected_cell,1)==1
                ev = search_event(app);
                ev_found = check_if_saved(app);
                if (ev_found==0 && isgraphics(app.currEvent.rect))
                    delete(app.currEvent.rect)
                end
                start_time = app.Events_array{app.selected_table}{ev}.start_time;
                end_time = app.Events_array{app.selected_table}{ev}.end_time;
                half_duration= (end_time-start_time)/2;
                current_time = start_time+half_duration-app.timeScale/2;
                app.currTime = max(0,current_time);
                update_limits(app);
                app.Slider.Value = app.currTime/60;
                app.currEvent = app.Events_array{app.selected_table}{ev}; %set as current event
            end
        end

        % Cell selection callback: spikes_Table
        function Spikes_selected_cell(app, event)

            app.selected_cell = event.Indices;
            app.selected_table = 2;
            if size(app.selected_cell,1)==1
                ev = search_event(app);
                ev_found = check_if_saved(app);
                if (ev_found==0 && isgraphics(app.currEvent.rect))
                    delete(app.currEvent.rect)
                end
                start_time = app.Events_array{app.selected_table}{ev}.start_time;
                end_time = app.Events_array{app.selected_table}{ev}.end_time;
                half_duration= (end_time-start_time)/2;
                current_time = start_time+half_duration-app.timeScale/2;
                app.currTime = max(0,current_time);
                update_limits(app);
                app.Slider.Value = app.currTime/60;
                app.currEvent = app.Events_array{app.selected_table}{ev}; %set as current event
            end
        end

        % Cell selection callback: art_Table
        function Artefacts_selected_cell(app, event)

            app.selected_cell = event.Indices;
            app.selected_table = 3;
            if size(app.selected_cell,1)==1
                ev = search_event(app);
                ev_found = check_if_saved(app);
                if (ev_found==0 && isgraphics(app.currEvent.rect))
                    delete(app.currEvent.rect)
                end
                start_time = app.Events_array{app.selected_table}{ev}.start_time;
                end_time = app.Events_array{app.selected_table}{ev}.end_time;
                half_duration= (end_time-start_time)/2;
                current_time = start_time+half_duration-app.timeScale/2;
                app.currTime = max(0,current_time);
                update_limits(app);
                app.Slider.Value = app.currTime/60;
                app.currEvent = app.Events_array{app.selected_table}{ev}; %set as current event
            end
        end

        % Cell selection callback: other_Table
        function Other_selected_cell(app, event)

            app.selected_cell = event.Indices;
            app.selected_table = 4;
            if size(app.selected_cell,1)==1
                ev = search_event(app);
                ev_found = check_if_saved(app);
                if (ev_found==0 && isgraphics(app.currEvent.rect))
                    delete(app.currEvent.rect)
                end
                start_time = app.Events_array{app.selected_table}{ev}.start_time;
                end_time = app.Events_array{app.selected_table}{ev}.end_time;
                half_duration= (end_time-start_time)/2;
                current_time = start_time+half_duration-app.timeScale/2;
                app.currTime = max(0,current_time);
                update_limits(app);
                app.Slider.Value = app.currTime/60;
                app.currEvent = app.Events_array{app.selected_table}{ev}; %set as current event
            end
        end

        % Value changing function: Slider
        function slider_moving(app, event)

            changingValue = event.Value;
            app.currTime = changingValue*60;
            pause(0.1);
            update_limits(app);
        end

        % Selection changed function: ButtonGroup
        function vigilance_choose(app, event)
            plot_vigilance(app);
        end

        % Menu selected function: SaveMenu
        function save_existing(app, event)

            if ~isempty(app.save_file_name) && ~ isempty(app.save_path_name)
                save_xls(app);
                app.Saved_Events_array = app.Events_array;
                app.Saved_vigilance_state = app.vigilance_state;
            end

        end

        % Menu selected function: SaveAsMenu
        function save_as(app, event)

            [file_name, path_name] = uiputfile({'*.xls','Excel File (*.xls*)'},'Save a File',app.file_name(1:end-4));
            if file_name==0 % user chose cancel
                return
            else
                app.save_file_name = file_name;
                app.save_path_name = path_name;
                save_xls(app);
                app.SaveMenu.Enable = 1;
                app.Saved_Events_array = app.Events_array;
                app.Saved_vigilance_state = app.vigilance_state;
            end

        end

        % Menu selected function: LoadAnnotationMenu
        function Load_annotation(app, event)

            [file_name,file_path,file_idx] = uigetfile({'*.xls','Excel files (*.xls*)';...
                '*.rhfe','RippleLab files (*.rhfe*)'},'Select a File', 'MultiSelect', 'off');
            if file_idx == 1
                Load_xls(app,file_name,file_path);
            else
                Load_rhfe(app,file_name,file_path);
            end

        end

        % Button pushed function: SpectrogramButton
        function power_spect(app, event)
            screensize = get( groot, 'Screensize' );
            fig_size = [screensize(3)/4.5 screensize(4)/1.4];
            if isempty(app.spect.minF)
                app.spect.minF = 30;
            end
            if isempty(app.spect.maxF)
                app.spect.maxF = 500;
            end
            if ~isempty(app.data)
                if sum(app.currSelectedChannels) == 1
                    if ~isempty(app.currEvent)
                        start_time = floor(app.currEvent.start_time*app.header.Fs);
                        end_time = floor(app.currEvent.end_time*app.header.Fs);
                        sig = app.data(app.currSelectedChannels==1,start_time:end_time);
                        % create a figure for power axis
                        f = figure('Position',[screensize(3)/2-fig_size(1)/2 screensize(4)/2-fig_size(2)/2 ...
                            fig_size(1) fig_size(2)],'MenuBar', 'none', 'ToolBar','none');
                        % create sig axis
                        ax_sig = axes(f,'Units', 'normalized','Position',[0.05 0.77 0.9 0.2]);
                        plot(ax_sig,sig,'k');
                        ax_sig.XLim = [0, (end_time-start_time)];
                        box(ax_sig,'on');
                        ax_sig.YTickLabel =[];ax_sig.XTickLabel =[];
                        % high pass filter
                        win = round(0.1*app.header.Fs);
                        strt = max(start_time-(end_time-start_time),1);
                        endt = min(end_time+(end_time-start_time),size(app.data,2));
                        sig = app.data(app.currSelectedChannels==1,strt:endt);
                        sig = bandpass(gather(sig),[app.spect.minF app.spect.maxF],app.header.Fs);
                        % create filterred signal axis
                        ax_filt_sig = axes(f,'Units', 'normalized','Position',[0.05 0.57 0.9 0.2 ]);
                        plot(ax_filt_sig,sig((end_time-start_time)+2:end-(end_time-start_time)),'k');
                        ax_filt_sig.XLim = [0, (end_time-start_time)];
                        box(ax_filt_sig,'on');
                        ax_filt_sig.YTickLabel =[];ax_filt_sig.XTickLabel =[];
                        % create power axis
                        ax_pow = axes(f,'Units', 'normalized','Position',[0.05 0.05 0.9 0.5 ],'Fontsize',20);
                        if length(sig)<win
                            disp('Use a longer segment for specrogram calculation')
                            return;
                        else
                            [s, t, f] = ...
                                f_GaborTransformWait(...
                                gather(sig),...
                                app.header.Fs,...
                                app.spect.minF, ... % min Freq
                                app.spect.maxF, ... % max Freq
                                512, ...
                                3, ...
                                1, ...
                                0, ...
                                0);
                            st = find(t>(end_time-start_time)/app.header.Fs,1);
                            et = find(t>(length(sig)-(end_time-start_time))/app.header.Fs,1);
                            s = s(:,st:et); t = t(st:et);
                            surf(ax_pow,t, f,abs(s), 'EdgeColor', 'none','FaceColor','interp');
                            axis xy;
                            axis tight;
                            colormap(jet); view(0,90);
                            ytickangle(ax_pow,90);
                            ax_pow.YRuler.TickLabelGapOffset = -2;
                            xlabel(ax_pow,'Time (s)');
                            range_of_ticks = round(min(f):(max(f)-min(f))/5:max(f));
                            yticks(ax_pow,range_of_ticks);
                        end

                    else
                        disp('no event selected');
                    end
                else
                    disp('Please select one channel only');
                end
            end

        end

        % Value changed function: minFreqEditField
        function set_min_Freq(app, event)
            value = app.minFreqEditField.Value;
            if ~isnan(str2double(value))
                app.spect.minF = str2double(value);
            else
                app.minFreqEditField.Value =  '';
            end
        end

        % Value changed function: maxFreqEditField
        function set_max_Freq(app, event)
            value = app.maxFreqEditField.Value;
            if ~isnan(str2double(value))
                app.spect.maxF = str2double(value);
            else
                app.maxFreqEditField.Value =  '';
            end
        end

        % Value changed function: FilterButton
        function Filter_all(app, event)

            plot_signals(app) ;
            app.UIAxes.XGrid = 'on';

        end

        % Menu selected function: xMenu_12
        function amp_scale_3(app, event)
            app.ampScale = 3;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 3x';
        end

        % Menu selected function: xMenu_5
        function amp_scale_2(app, event)
            app.ampScale = 2;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 2x';
        end

        % Menu selected function: xMenu_6
        function amp_scale_5(app, event)
            app.ampScale = 5;
            plot_signals(app);
            app.Amplitude1xLabel.Text = 'Amplitude 5x';
        end

        % Close request function: AnnotatorUIFigure
        function closing_annotator(app, event)
            if (~isequal(app.Events_array,app.Saved_Events_array) || ~isequal(app.Saved_vigilance_state,app.vigilance_state))
                selection = questdlg('Want to save changes?', ...
                    'Annotator', ...
                    'Save','Do not Save','Cancel','Save');
                switch selection
                    case 'Do not Save'
                        delete(app);
                    case 'Cancel'
                        return
                    case 'Save'
                        if app.SaveMenu.Enable == 1
                            if ~isempty(app.save_file_name) && ~ isempty(app.save_path_name)
                                save_xls(app);
                                app.Saved_Events_array = app.Events_array;
                            end
                        else
                            [file_name, path_name] = uiputfile({'*.xls','Excel File (*.xls*)'},'Save a File');
                            if file_name==0 % user chose cancel
                                return
                            else
                                app.save_file_name = file_name;
                                app.save_path_name = path_name;
                                save_xls(app);
                            end

                        end
                end
            else
                delete(app);
            end
        end

        % Value changed function: EnableStateScoringCheckBox
        function Enable_Scoring(app, event)
            value = app.EnableStateScoringCheckBox.Value;
            if value == 1
                app.ButtonGroup.Enable = 'on';
                plot_vigilance(app);
            else
                app.ButtonGroup.Enable = 'off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AnnotatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {511, 511};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {709, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnnotatorUIFigure = uifigure('Visible', 'off');
            app.AnnotatorUIFigure.Name = 'Annotator';
            app.AnnotatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @closing_annotator, true);
            app.AnnotatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AnnotatorUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @create_event, true);
            app.AnnotatorUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @set_end_time, true);
            app.AnnotatorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @widen_rect, true);
            app.AnnotatorUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @scroll_slider, true);
            app.AnnotatorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @keyboard_pressed, true);
            app.GridLayout = uigridlayout(app.AnnotatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @vigilance_choose, true);
            app.ButtonGroup.Enable = 'off';
            app.ButtonGroup.Title = ' ';
            app.UndefinedButton = uiradiobutton(app.ButtonGroup);
            app.UndefinedButton.Text = 'Undefined';
            app.UndefinedButton.Value = true;
            app.AwakeButton = uiradiobutton(app.ButtonGroup);
            app.AwakeButton.Text = 'Awake';
            app.REMButton = uiradiobutton(app.ButtonGroup);
            app.REMButton.Text = 'REM';
            app.nonREMButton = uiradiobutton(app.ButtonGroup);
            app.nonREMButton.Text = 'non-REM';
            app.EventsButtonGroup = uibuttongroup(app.LeftPanel);
            app.EventsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @choose_event_type, true);
            app.EventsButtonGroup.Title = 'Events';
            app.HFOsButton = uiradiobutton(app.EventsButtonGroup);
            app.HFOsButton.Text = 'HFOs';
            app.SpikesButton = uiradiobutton(app.EventsButtonGroup);
            app.SpikesButton.Text = 'Spikes';
            app.ArtefactsButton = uiradiobutton(app.EventsButtonGroup);
            app.ArtefactsButton.Text = 'Artefacts';
            app.ArtefactsButton.Value = true;
            app.OtherButton = uiradiobutton(app.EventsButtonGroup);
            app.OtherButton.Text = 'Other';
            app.Panel = uipanel(app.LeftPanel);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes.ZTickLabel = '';
            app.Slider = uislider(app.Panel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @slider_moved, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @slider_moving, true);
            app.ch1Button_s = uibutton(app.Panel, 'state');
            app.ch1Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch1, true);
            app.ch1Button_s.Visible = 'off';
            app.ch1Button_s.Text = 'ch1';
            app.ch2Button_s = uibutton(app.Panel, 'state');
            app.ch2Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch2, true);
            app.ch2Button_s.Visible = 'off';
            app.ch2Button_s.Text = 'ch2';
            app.ch3Button_s = uibutton(app.Panel, 'state');
            app.ch3Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch3, true);
            app.ch3Button_s.Visible = 'off';
            app.ch3Button_s.Text = 'ch3';
            app.ch4Button_s = uibutton(app.Panel, 'state');
            app.ch4Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch4, true);
            app.ch4Button_s.Visible = 'off';
            app.ch4Button_s.Text = 'ch4';
            app.ch5Button_s = uibutton(app.Panel, 'state');
            app.ch5Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch5, true);
            app.ch5Button_s.Visible = 'off';
            app.ch5Button_s.Text = 'ch5';
            app.ch6Button_s = uibutton(app.Panel, 'state');
            app.ch6Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch6, true);
            app.ch6Button_s.Visible = 'off';
            app.ch6Button_s.Text = 'ch6';
            app.ch7Button_s = uibutton(app.Panel, 'state');
            app.ch7Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch7, true);
            app.ch7Button_s.Visible = 'off';
            app.ch7Button_s.Text = 'ch7';
            app.ch8Button_s = uibutton(app.Panel, 'state');
            app.ch8Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch8, true);
            app.ch8Button_s.Visible = 'off';
            app.ch8Button_s.Text = 'ch8';
            app.ch9Button_s = uibutton(app.Panel, 'state');
            app.ch9Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch9, true);
            app.ch9Button_s.Visible = 'off';
            app.ch9Button_s.Text = 'ch9';
            app.ch10Button_s = uibutton(app.Panel, 'state');
            app.ch10Button_s.ValueChangedFcn = createCallbackFcn(app, @select_ch10, true);
            app.ch10Button_s.Visible = 'off';
            app.ch10Button_s.Text = 'ch10';
            app.ampScaleLabel = uilabel(app.Panel);
            app.Time_Label = uilabel(app.Panel);
            app.Time_Label.Text = '0:00';
            app.Amplitude1xLabel = uilabel(app.LeftPanel);
            app.Amplitude1xLabel.Text = 'Amplitude  1x';
            app.secondLabel = uilabel(app.LeftPanel);
            app.secondLabel.Text = ' 5 second';
            app.ch3Button = uibutton(app.LeftPanel, 'state');
            app.ch3Button.ValueChangedFcn = createCallbackFcn(app, @ch3_stateChanged, true);
            app.ch3Button.Visible = 'off';
            app.ch3Button.Text = 'ch3';
            app.ch5Button = uibutton(app.LeftPanel, 'state');
            app.ch5Button.ValueChangedFcn = createCallbackFcn(app, @ch5_stateChanged, true);
            app.ch5Button.Visible = 'off';
            app.ch5Button.Text = 'ch5';
            app.ch6Button = uibutton(app.LeftPanel, 'state');
            app.ch6Button.ValueChangedFcn = createCallbackFcn(app, @ch6_stateChanged, true);
            app.ch6Button.Visible = 'off';
            app.ch6Button.Text = 'ch6';
            app.ch8Button = uibutton(app.LeftPanel, 'state');
            app.ch8Button.ValueChangedFcn = createCallbackFcn(app, @ch8_stateChanged, true);
            app.ch8Button.Visible = 'off';
            app.ch8Button.Text = 'ch8';
            app.ch9Button = uibutton(app.LeftPanel, 'state');
            app.ch9Button.ValueChangedFcn = createCallbackFcn(app, @ch9_stateChanged, true);
            app.ch9Button.Visible = 'off';
            app.ch9Button.Text = 'ch9';
            app.ch2Button = uibutton(app.LeftPanel, 'state');
            app.ch2Button.ValueChangedFcn = createCallbackFcn(app, @ch2_stateChanged, true);
            app.ch2Button.Visible = 'off';
            app.ch2Button.Text = 'ch2';
            app.ch4Button = uibutton(app.LeftPanel, 'state');
            app.ch4Button.ValueChangedFcn = createCallbackFcn(app, @ch4_stateChanged, true);
            app.ch4Button.Visible = 'off';
            app.ch4Button.Text = 'ch4';
            app.ch7Button = uibutton(app.LeftPanel, 'state');
            app.ch7Button.ValueChangedFcn = createCallbackFcn(app, @ch7_stateChanged, true);
            app.ch7Button.Visible = 'off';
            app.ch7Button.Text = 'ch7';
            app.ch10Button = uibutton(app.LeftPanel, 'state');
            app.ch10Button.ValueChangedFcn = createCallbackFcn(app, @ch10_stateChanged, true);
            app.ch10Button.Visible = 'off';
            app.ch10Button.Text = 'ch10';
            app.ch1Button_11 = uibutton(app.LeftPanel, 'state');
            app.ch1Button_11.ValueChangedFcn = createCallbackFcn(app, @ch1_stateChanged, true);
            app.ch1Button_11.Visible = 'off';
            app.ch1Button_11.Text = 'ch1';
            app.Panel_2 = uipanel(app.LeftPanel);
            app.minFreqEditFieldLabel = uilabel(app.Panel_2);
            app.minFreqEditFieldLabel.Text = 'min Freq.';
            app.minFreqEditField = uieditfield(app.Panel_2, 'text');
            app.minFreqEditField.ValueChangedFcn = createCallbackFcn(app, @set_min_Freq, true);
            app.minFreqEditField.Value = '30';
            app.maxFreqLabel = uilabel(app.Panel_2);
            app.maxFreqLabel.Text = 'max Freq.';
            app.maxFreqEditField = uieditfield(app.Panel_2, 'text');
            app.maxFreqEditField.ValueChangedFcn = createCallbackFcn(app, @set_max_Freq, true);
            app.maxFreqEditField.Value = '250';
            app.SpectrogramButton = uibutton(app.Panel_2, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @power_spect, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.FilterButton = uibutton(app.Panel_2, 'state');
            app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @Filter_all, true);
            app.FilterButton.Text = 'Filter ';
            app.ampScaleLabel_2 = uilabel(app.LeftPanel);
            app.ampScaleLabel_3 = uilabel(app.LeftPanel);
            app.EnableStateScoringCheckBox = uicheckbox(app.LeftPanel);
            app.EnableStateScoringCheckBox.ValueChangedFcn = createCallbackFcn(app, @Enable_Scoring, true);
            app.EnableStateScoringCheckBox.Text = 'Enable State Scoring';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.HFOsTab = uitab(app.TabGroup);
            app.HFOsTab.Title = 'HFOs';
            app.hfo_Table = uitable(app.HFOsTab);
            app.hfo_Table.ColumnName = {'Label'; 'Start Time'; 'End Time'; 'Channel'; 'ID'};
            app.hfo_Table.RowName = {};
            app.hfo_Table.CellSelectionCallback = createCallbackFcn(app, @HFO_selected_cell, true);
            app.SpikesTab = uitab(app.TabGroup);
            app.SpikesTab.Title = 'Spikes';
            app.spikes_Table = uitable(app.SpikesTab);
            app.spikes_Table.ColumnName = {'Label'; 'Start Time'; 'End Time'; 'Channel'; 'ID'};
            app.spikes_Table.RowName = {};
            app.spikes_Table.CellSelectionCallback = createCallbackFcn(app, @Spikes_selected_cell, true);
            app.ArtefactsTab = uitab(app.TabGroup);
            app.ArtefactsTab.Title = 'Artefacts';
            app.art_Table = uitable(app.ArtefactsTab);
            app.art_Table.ColumnName = {'Label'; 'Start Time'; 'End Time'; 'Channel'; 'ID'};
            app.art_Table.RowName = {''};
            app.art_Table.CellSelectionCallback = createCallbackFcn(app, @Artefacts_selected_cell, true);
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.other_Table = uitable(app.OtherTab);
            app.other_Table.ColumnName = {'Label'; 'Start Time'; 'End Time'; 'Channel'; 'ID'};
            app.other_Table.RowName = {};
            app.other_Table.CellSelectionCallback = createCallbackFcn(app, @Other_selected_cell, true);
            app.FileMenu = uimenu(app.AnnotatorUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenEDFMenu = uimenu(app.FileMenu);
            app.OpenEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @Open_EDF, true);
            app.OpenEDFMenu.Text = 'Open EDF';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @save_existing, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save ';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @save_as, true);
            app.SaveAsMenu.Enable = 'off';
            app.SaveAsMenu.Text = 'Save As';
            app.LoadAnnotationMenu = uimenu(app.FileMenu);
            app.LoadAnnotationMenu.MenuSelectedFcn = createCallbackFcn(app, @Load_annotation, true);
            app.LoadAnnotationMenu.Enable = 'off';
            app.LoadAnnotationMenu.Text = 'Load Annotation';
            app.Menu = uimenu(app.FileMenu);
            app.AmplitudeMenu = uimenu(app.AnnotatorUIFigure);
            app.AmplitudeMenu.Text = 'Amplitude';
            app.xMenu = uimenu(app.AmplitudeMenu);
            app.xMenu.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_100, true);
            app.xMenu.Text = '0.1x';
            app.xMenu_2 = uimenu(app.AmplitudeMenu);
            app.xMenu_2.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_200, true);
            app.xMenu_2.Text = '0.2x';
            app.xMenu_3 = uimenu(app.AmplitudeMenu);
            app.xMenu_3.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_500, true);
            app.xMenu_3.Text = '0.5x';
            app.xMenu_4 = uimenu(app.AmplitudeMenu);
            app.xMenu_4.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_1, true);
            app.xMenu_4.Text = '1.0x';
            app.xMenu_5 = uimenu(app.AmplitudeMenu);
            app.xMenu_5.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_2, true);
            app.xMenu_5.Text = '2.0x';
            app.xMenu_12 = uimenu(app.AmplitudeMenu);
            app.xMenu_12.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_3, true);
            app.xMenu_12.Text = '3.0x';
            app.xMenu_13 = uimenu(app.AmplitudeMenu);
            app.xMenu_13.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_4, true);
            app.xMenu_13.Text = '4.0x';
            app.xMenu_6 = uimenu(app.AmplitudeMenu);
            app.xMenu_6.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_5, true);
            app.xMenu_6.Text = '5.0x';
            app.xMenu_7 = uimenu(app.AmplitudeMenu);
            app.xMenu_7.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_10, true);
            app.xMenu_7.Text = '10.x';
            app.xMenu_8 = uimenu(app.AmplitudeMenu);
            app.xMenu_8.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_15, true);
            app.xMenu_8.Text = '15x';
            app.xMenu_9 = uimenu(app.AmplitudeMenu);
            app.xMenu_9.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_20, true);
            app.xMenu_9.Text = '20x';
            app.xMenu_10 = uimenu(app.AmplitudeMenu);
            app.xMenu_10.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_40, true);
            app.xMenu_10.Text = '40x';
            app.xMenu_11 = uimenu(app.AmplitudeMenu);
            app.xMenu_11.MenuSelectedFcn = createCallbackFcn(app, @amp_scale_100x, true);
            app.xMenu_11.Text = '100x';
            app.TimeScaleMenu = uimenu(app.AnnotatorUIFigure);
            app.TimeScaleMenu.Text = 'TimeScale';
            app.secpageMenu = uimenu(app.TimeScaleMenu);
            app.secpageMenu.MenuSelectedFcn = createCallbackFcn(app, @time_scale_100m, true);
            app.secpageMenu.Text = '0.1 sec/page';
            app.secpageMenu_2 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @time_scale_200m, true);
            app.secpageMenu_2.Text = '0.2 sec/page';
            app.secpageMenu_3 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_3.MenuSelectedFcn = createCallbackFcn(app, @time_scale_500m, true);
            app.secpageMenu_3.Text = '0.5 sec/page';
            app.secpageMenu_4 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_4.MenuSelectedFcn = createCallbackFcn(app, @time_scale_1s, true);
            app.secpageMenu_4.Text = '1.0 sec/page';
            app.secpageMenu_5 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_5.MenuSelectedFcn = createCallbackFcn(app, @time_scale_2s, true);
            app.secpageMenu_5.Text = '2.0 sec/page';
            app.secpageMenu_6 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_6.MenuSelectedFcn = createCallbackFcn(app, @time_scale_3s, true);
            app.secpageMenu_6.Text = '3.0 sec/page';
            app.secpageMenu_7 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_7.MenuSelectedFcn = createCallbackFcn(app, @time_scale_5s, true);
            app.secpageMenu_7.Text = '5.0 sec/page';
            app.secpageMenu_8 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_8.MenuSelectedFcn = createCallbackFcn(app, @time_scale_10s, true);
            app.secpageMenu_8.Text = '10 sec/page';
            app.secpageMenu_9 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_9.MenuSelectedFcn = createCallbackFcn(app, @time_scale_15s, true);
            app.secpageMenu_9.Text = '15 sec/page';
            app.secpageMenu_10 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_10.MenuSelectedFcn = createCallbackFcn(app, @time_scale_20s, true);
            app.secpageMenu_10.Text = '20 sec/page';
            app.secpageMenu_11 = uimenu(app.TimeScaleMenu);
            app.secpageMenu_11.MenuSelectedFcn = createCallbackFcn(app, @time_scale_30s, true);
            app.secpageMenu_11.Text = '30 sec/page';
            app.minpageMenu = uimenu(app.TimeScaleMenu);
            app.minpageMenu.MenuSelectedFcn = createCallbackFcn(app, @time_scale_60s, true);
            app.minpageMenu.Text = '1.0 min/page';
            app.minpageMenu_2 = uimenu(app.TimeScaleMenu);
            app.minpageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @time_scale_120s, true);
            app.minpageMenu_2.Text = '2.0 min/page';
            app.minpageMenu_3 = uimenu(app.TimeScaleMenu);
            app.minpageMenu_3.MenuSelectedFcn = createCallbackFcn(app, @time_scale_5m, true);
            app.minpageMenu_3.Text = '5.0 min/page';
            app.AboutMenu = uimenu(app.AnnotatorUIFigure);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.AnnotatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnnotatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnnotatorUIFigure)
        end
    end
end